classdef Manual_classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NoneofthoseButton              matlab.ui.control.Button
        IamsurePanel                   matlab.ui.container.Panel
        AWAKEButton                    matlab.ui.control.Button
        NREMButton                     matlab.ui.control.Button
        REMButton                      matlab.ui.control.Button
        TransitionPanel                matlab.ui.container.Panel
        AWAKENREMButton                matlab.ui.control.Button
        NREMREMButton                  matlab.ui.control.Button
        REMAWAKEButton                 matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        FinishreinspectionButton       matlab.ui.control.Button
        StopandsaveinspectionButton    matlab.ui.control.Button
        AWAKELabel                     matlab.ui.control.Label
        NREMLabel                      matlab.ui.control.Label
        REMLabel                       matlab.ui.control.Label
        NumberofepochsclassifiedLabel  matlab.ui.control.Label
        Label_awake                    matlab.ui.control.Label
        Label_nrem                     matlab.ui.control.Label
        Label_rem                      matlab.ui.control.Label
        Remaining_number               matlab.ui.control.Label
        RemainingLabel                 matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        LoadDataButton                 matlab.ui.control.Button
        stLabel                        matlab.ui.control.Label
        SelecttheDirectorycontainingdata_variablesandALL_DATAfilesLabel  matlab.ui.control.Label
        NotchHzEditFieldLabel          matlab.ui.control.Label
        NotchHzEditField               matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        ndLabel                        matlab.ui.control.Label
        CompleteInspectionButton       matlab.ui.control.Button
        rdLabel                        matlab.ui.control.Label
        SelectepochLabel               matlab.ui.control.Label
        SelectepochListBox             matlab.ui.control.ListBox
        CheckBox_AWAKE                 matlab.ui.control.CheckBox
        CheckBox_NREM                  matlab.ui.control.CheckBox
        CheckBox_REM                   matlab.ui.control.CheckBox
        CheckBox_remaining             matlab.ui.control.CheckBox
        None_number                    matlab.ui.control.Label
        NoneLabel                      matlab.ui.control.Label
        CheckBox_None                  matlab.ui.control.CheckBox
        WKNREMLabel                    matlab.ui.control.Label
        NREMREMLabel                   matlab.ui.control.Label
        REMWKLabel                     matlab.ui.control.Label
        Label_awake_nrem               matlab.ui.control.Label
        Label_nrem_rem                 matlab.ui.control.Label
        Label_rem_wk                   matlab.ui.control.Label
        CheckBox_AWAKE_NREM            matlab.ui.control.CheckBox
        CheckBox_NREM_REM              matlab.ui.control.CheckBox
        CheckBox_REM_WK                matlab.ui.control.CheckBox
        MicroarousalPanel              matlab.ui.container.Panel
        NREM_microarousal_button       matlab.ui.control.Button
        REM_microarousal_button        matlab.ui.control.Button
        RandomizeCheckBox              matlab.ui.control.CheckBox
        SelectedEpochsCheckBox         matlab.ui.control.CheckBox
        UIAxes_CA1                     matlab.ui.control.UIAxes
        UIAxes_PSD                     matlab.ui.control.UIAxes
        UIAxes_EMG                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Current_classification % is a value representing the classification for the current period (jj)
        LFP % Spectral Power and FS
        DATA % LFP itself
        EMG % EMG RMS and FS
        All_Sort % Classification vector
        Notch_frequencies % Get the notch frequency values
        Visual_inspection % Get the visual inspection values
        Output_path % Get the output path (get and save files)
        Last_checked % Get the last checked period
        Epochs_list_num % Vector with the epochs that will be presented to the user
        Epochs_list_cell % String cell with epochs number
        Epoch_list_selected % Scalar indicating the selected epoch
        SelectedEpochs % Specific epochs that are going to be labeled
    end
    
    properties (Access = private)
        NaNEpochs % Defines which epochs have NaN values (artifacts)
    end
    
    methods (Access = public)
        
        % Function to update the epochs, PSD and scatter plot on visual
        % inspection (epoch_num = selected epoch to be plotted;
        % CA1_lfp = CA1 LFP data (selected epoch)
        % EMG_rec = EMG recorded data (selected epoch)
        function updatePlot(app, time_vector_LFP, time_vector_EMG, epoch_num, CA1_lfp, EMG_rec, freq_vector, PSD_data)
            sprintf('plot:')
            tic
            
            % Get the handle for each one of the axes
            CA1_UI = app.UIAxes_CA1;
            EMG_UI = app.UIAxes_EMG;
            PSD_UI = app.UIAxes_PSD;
            
            %% Plot
            % CA1
            plot(CA1_UI, time_vector_LFP, CA1_lfp)
            title(CA1_UI, ['Time bin: ' num2str(epoch_num)])   % Change the number of the title
            
            % EMG
            plot(EMG_UI, time_vector_EMG, EMG_rec)
            
            % PSD
            loglog(PSD_UI,freq_vector,nanfastsmooth(PSD_data,10,1,0.5),'k','linewidth',1.2)
            % hold(PSD_UI,'on')
            % loglog(PSD_UI,freq_vector,smooth(PSD_data,10),'r','linewidth',0.5)
            ylim(PSD_UI,[0.00001 0.03])
            %             ylim(PSD_UI,[min(PSD_data)*0.5 max(PSD_data)*3]);    % Correct Ylim accordingly to the data
            % hold(PSD_UI,'off')            
            
            % Update the counters
            app.Label_awake.Text = mat2str(length(find(app.All_Sort == 3)));  % AWAKE
            app.Label_nrem.Text = mat2str(length(find(app.All_Sort == 2)));    % NREM
            app.Label_rem.Text = mat2str(length(find(app.All_Sort == 1)));      % REM
            app.Label_awake_nrem.Text = mat2str(length(find(app.All_Sort == 4)));  % AWAKE-NREM
            app.Label_nrem_rem.Text = mat2str(length(find(app.All_Sort == 5)));    % NREM-REM
            app.Label_rem_wk.Text = mat2str(length(find(app.All_Sort == 6)));      % REM-WK
            app.Remaining_number.Text = mat2str(length(find(isnan(app.All_Sort))));
            app.None_number.Text = mat2str(length(find(app.All_Sort == 0)));      % None
            
            toc
            % Update the plots
            drawnow
        end
    end
    
    methods (Access = private)
        % Function to process the data
        function filt_data(app)
            
            % Get the notch frequency
            app.Notch_frequencies.base = app.NotchHzEditField.Value;
            % Notch frenquency boundaries (Hz)
            app.Notch_frequencies.extension = 5;
            % Include the harmonics
            app.Notch_frequencies.LFP = app.Notch_frequencies.base:app.Notch_frequencies.base:app.LFP.LFP.FS/2 - app.Notch_frequencies.extension;
            app.Notch_frequencies.EMG = app.Notch_frequencies.base:app.Notch_frequencies.base:app.EMG.EMG.FS/2 - app.Notch_frequencies.extension;
            
            
            % EMG
            % Loop for each of the harmonics
            for harm = 1:length(app.Notch_frequencies.EMG)
                
                % Create the filter (Butter 2nd order)
                fs = app.EMG.EMG.FS; % sampling_frequency
                n = 2; % filt order
                nyquist_rate = fs/2;
                Wn = [app.Notch_frequencies.EMG(harm)-app.Notch_frequencies.extension app.Notch_frequencies.EMG(harm)+app.Notch_frequencies.extension]/nyquist_rate;  % Lower and Upper frequency limits
                ftype = 'stop'; % filter type
                [b,a] = butter(n,Wn,ftype); % Create the butter filter
                
                % Filtering EMG data
                app.DATA.EMG_epochs = filter(b,a,app.DATA.EMG_epochs,[],2);
            end
            
            % LFP
            % Loop for each of the harmonics
            for harm = 1:length(app.Notch_frequencies.LFP)
                
                % Create the filter (Butter 2nd order)
                fs = app.LFP.LFP.FS; % sampling_frequency
                n = 2; % filt order
                nyquist_rate = fs/2;
                Wn = [app.Notch_frequencies.LFP(harm)-app.Notch_frequencies.extension app.Notch_frequencies.LFP(harm)+app.Notch_frequencies.extension]/nyquist_rate;  % Lower and Upper frequency limits
                ftype = 'stop'; % filter type
                [b,a] = butter(n,Wn,ftype); % Create the butter filter
                
                % Filtering EMG data
                app.DATA.LFP_epochs = filter(b,a,app.DATA.LFP_epochs,[],2);
            end
            
        end
        
        % Function to update the epochs list when the user checks or
        % unchecks a box
        function updateEpochs_complete(app)
            mask = false(1,length(app.All_Sort));
            % Check whichs boxes are checked
            % AWAKE
            if app.CheckBox_AWAKE.Value == true
                mask(app.All_Sort == 3) = true;
            end
            % NREM
            if app.CheckBox_NREM.Value == true
                mask(app.All_Sort == 2) = true;
            end
            % REM
            if app.CheckBox_REM.Value == true
                mask(app.All_Sort == 1) = true;
            end
            % WK-NREM
            if app.CheckBox_AWAKE_NREM.Value == true
                mask(app.All_Sort == 4) = true;
            end
            % REM-WK
            if app.CheckBox_REM_WK.Value == true
                mask(app.All_Sort == 6) = true;
            end
            % NREM-REM
            if app.CheckBox_NREM_REM.Value == true
                mask(app.All_Sort == 5) = true;
            end
            % Remaining (NaN)
            if app.CheckBox_remaining.Value == true
                mask(isnan(app.All_Sort)) = true;
                % Ignore the artifact epochs
                mask(app.NaNEpochs) = false;
            end
            % Unclassified (None of those)
            if app.CheckBox_None.Value == true
                mask(app.All_Sort == 0) = true;
            end
            
            % Organize the epochs into a vector
            app.Epochs_list_num = find(mask);
            
            % Check whether the check box is empty or not
            if ~isempty(app.Epochs_list_num)
                selected_epochs_txt = num2str(app.Epochs_list_num);
                % Orgnize the epochs into a string cell
                selected_epochs_cell = textscan(deblank(selected_epochs_txt),'%s');
                app.Epochs_list_cell = selected_epochs_cell{1}';
                % Insert the selected epochs into the epoch list
                app.SelectepochListBox.Items = app.Epochs_list_cell;
            else
                % Insert 'Empty' into the epoch list
                app.SelectepochListBox.Items = {''};
            end
            
            drawnow()
            
        end
        
        % Gets the current epoch and finds the nearest epoch from the list
        % (next or previous one) and return it
        function jj = select_from_list(app,jj,direction_char)
            epochs_in_list = str2double(app.SelectepochListBox.Items);  % Extract the epochs inside the list as a double vector
            switch direction_char   % Get if user has selected the next or previous epoch
                case 'next'
                    epochs_in_list(epochs_in_list <= jj) = [];                  % Erase the epochs before or equal the current one
                case 'previous'
                    epochs_in_list(epochs_in_list >= jj) = [];                  % Erase the epochs before or equal the current one
            end
            [~, indexOfMin] = min(abs(epochs_in_list-jj));    % Gets the nearest epoch
            
            % Check if the nearest epoch is the first or last from the list
            % If it is the first or last epoch
            if isempty(epochs_in_list)
                jj = jj - 1;                          % jj = the nearest epoch (the -1 is important since the while loop will add 1 anyways)
            else
                jj = epochs_in_list(indexOfMin)-1;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             exportapp(app.UIFigure,'Plot_app.pdf')
            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            % If the user presses a key instead of pressing a classfication
            % button -> Get the key and act accordingly
            % event.Key = pressed key
            
            % Act accordingly
            switch event.Key
                case {'1', 'numpad1'}    % AWAKE
                    app.Current_classification = 1; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'2', 'numpad2'}    % NREM
                    app.Current_classification = 2; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'3', 'numpad3'}    % REM
                    app.Current_classification = 3; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'4', 'numpad4'}    % AWAKE <--> NREM
                    app.Current_classification = 4; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'5', 'numpad5'}    % NREM <--> REM
                    app.Current_classification = 5; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'6', 'numpad6'}    % REM <--> AWAKE
                    app.Current_classification = 6; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'0', 'numpad0'}    % None of those
                    app.Current_classification = 0; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'7', 'numpad7'}    % NREM micro-arousal
                    app.Current_classification = 7; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case {'8', 'numpad8'}    % NREM micro-arousal
                    app.Current_classification = 8; % Change the current epoch classification accordingly
                    uiresume(app.UIFigure)  % Resume the execution of the visual inspection
                    
                case 'rightarrow'   % Right arrow (next)
                    app.Current_classification = 'next'; % Change the current epoch classification accordingly (informs the algorithm to go back to the previous epoch)
                    uiresume(app.UIFigure) % Resume the execution of the visual inspection
                    
                case 'leftarrow'   % Right arrow (next)
                    app.Current_classification = 'previous'; % Change the current epoch classification accordingly (informs the algorithm to go forward to the next epoch)
                    uiresume(app.UIFigure) % Resume the execution of the visual inspection
                    
                case 'add'
                    app.Current_classification = 'next_from_list'; % Change the current epoch classification accordingly (informs the algorithm to go forward to the next epoch from te list)
                    uiresume(app.UIFigure) % Resume the execution of the visual inspection
                
                case 'subtract'
                    app.Current_classification = 'previous_from_list'; % Change the current epoch classification accordingly (informs the algorithm to go back to the previous epoch from the list)
                    uiresume(app.UIFigure) % Resume the execution of the visual inspection                    
                    
            end
            
        end

        % Button pushed function: AWAKEButton
        function AWAKEButtonPushed(app, event)
            sprintf('Classification:')
            tic
            app.Current_classification = 1; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
            toc
        end

        % Button pushed function: NREMButton
        function NREMButtonPushed(app, event)
            app.Current_classification = 2; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: REMButton
        function REMButtonPushed(app, event)
            app.Current_classification = 3; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: AWAKENREMButton
        function AWAKENREMButtonPushed(app, event)
            app.Current_classification = 4; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: NREMREMButton
        function NREMREMButtonPushed(app, event)
            app.Current_classification = 5; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: REMAWAKEButton
        function REMAWAKEButtonPushed(app, event)
            app.Current_classification = 6; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: NoneofthoseButton
        function NoneofthoseButtonPushed(app, event)
            app.Current_classification = 0; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Callback function
        function EpochcounterButtonPushed(app, event)
            app.Current_classification = 7; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % Return to the previous plotted epoch
            app.Current_classification = 'previous'; % Change the current epoch classification accordingly (informs the algorithm to go back to the previous epoch)
            uiresume(app.UIFigure) % Resume the execution of the visual inspection
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Return to the previous plotted epoch
            app.Current_classification = 'next'; % Change the current epoch classification accordingly (informs the algorithm to go forward to the previous epoch)
            uiresume(app.UIFigure) % Resume the execution of the visual inspection
            
            %             exportgraphics(app.UIFigure,'Virtual.pdf','BackgroundColor','none','ContentType','vector')
        end

        % Button pushed function: FinishreinspectionButton
        function FinishreinspectionButtonPushed(app, event)
            app.Current_classification = 'finish_re_inspection';    % Change the current epoch classification to finish to re-inspection
            uiresume(app.UIFigure) % Resume the execution of the visual inspection
        end

        % Button pushed function: StopandsaveinspectionButton
        function StopandsaveinspectionButtonPushed(app, event)
            app.Current_classification = 'stop_and_save';    % Change the current epoch classification to finish the inspection and save it
            uiresume(app.UIFigure) % Resume the execution of the visual inspection
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Get the file names correctly
            app.Output_path = uigetdir('','Select the Directory containing the data_variables and ALL_DATA files');  % Get the directory (starting from the working directory)
            % Stop the the loading if the user have not selected a
            % directory
            if app.Output_path == 0
                return
            end
            
            % Get the full name of the output folder (ALL_DATA)
            data_full_path = fullfile(app.Output_path,'ALL_DATA.mat');
            % Get the full name of the output folder (LFP)
            lfp_full_path = fullfile(app.Output_path,'data_variables.mat');
            % Get the full name of a previous manual inspection
            manual_classification_full_path = fullfile(app.Output_path,'Manual_classification.mat');
                        
            % Load them!
            app.LFP = load(lfp_full_path,'LFP');
            app.EMG = load(lfp_full_path,'EMG');
            % Check if the data was saved using the struct mode
            listOfVariables = who('-file', data_full_path); % Get the list of variables inside it
            if ismember('LFP_epochs', listOfVariables) % Check if it has any of fields saved as variables
                app.DATA = load(data_full_path,'LFP_epochs','EMG_epochs','EMG_processed_sampling_frequency');    % Load only the necessary fields (only when save with the -struct option)
            else
                load(data_full_path,'DATA') % Default load (Slower)
                % Remove the extra fields
                fields = {'EMG_hour','EMG_raw_data','LFP_hour','LFP_raw_data'};
                app.DATA = rmfield(DATA,fields);
                clear fields
                clear DATA
            end
            
            % Check if the Manual Inspection file exists
            if isfile(manual_classification_full_path)
                load(manual_classification_full_path)
                app.All_Sort = all_Sort;
                app.Visual_inspection = visual_inspection;
                app.Last_checked = last_checked;
            else
                % Variable with classification info (create if they don't
                % exist yet)
                app.Last_checked = false;
                app.All_Sort = nan(1,size(app.LFP.LFP.Power_normalized,1));
                app.Visual_inspection.AWAKE_idx=[];
                app.Visual_inspection.NREM_idx=[];
                app.Visual_inspection.REM_idx=[];
                app.Visual_inspection.Transition.AWA_NREM=[];
                app.Visual_inspection.Transition.NREM_REM=[];
                app.Visual_inspection.Transition.REM_AWA=[];
                app.Visual_inspection.Transition.unknown=[];
                app.Visual_inspection.inspection_finished = false;
            end
            
            % Define the NaN epochs (artifacts)
            app.NaNEpochs = find(isnan(app.DATA.LFP_epochs(:,1)));
            app.All_Sort(app.NaNEpochs) = 0;
                        
            % Process data
            filt_data(app)
            
            % Update the epochs list
            updateEpochs_complete(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Time vector for plot
            time_vector_LFP=(1:1:size(app.DATA.LFP_epochs,2))./app.LFP.LFP.FS;
            if isfield(app.DATA,'EMG_processed_sampling_frequency')  % Check if the field FS exists
                time_vector_EMG=(1:1:size(app.DATA.EMG_epochs,2))./app.DATA.EMG_processed_sampling_frequency;  % Uses the sampling frequency of the EMG
            else
                time_vector_EMG=(1:1:size(app.DATA.EMG_epochs,2))./app.LFP.LFP.FS;  % Uses the sampling frequency of the LFP
            end
            
            % Define the initial classification status
            app.Current_classification = 'hold';
            
            % Show the number of remaining epochs to be classified
            app.Remaining_number.Text = mat2str(size(app.DATA.EMG_epochs,1));
            
            % Initialize the parameters for the main loop
            condition=1;
            
            if ~(app.Visual_inspection.inspection_finished) && logical(app.Last_checked)
                jj = app.Last_checked-1;
            else 
                jj = 0;
            end
            
            % Start the main Loop
            while condition==1
                % Increase the counter
                
                % Ramdomize the epochs if the 'Randomize' checkbox has been
                % selected
                if app.RandomizeCheckBox.Value && (strcmp(app.Current_classification,'next') || strcmp(app.Current_classification,'previous') || isnumeric(app.Current_classification))
                    unclassified = find(isnan(app.All_Sort));       % Get the unclassified epochs
                    jj = unclassified(randperm(length(unclassified),1))-1;    % Get the random epoch from the unclassified ones    
                end
                
                % If the user has checked the option to label only specific
                % epochs
                if app.SelectedEpochsCheckBox.Value && (strcmp(app.Current_classification,'next') || strcmp(app.Current_classification,'previous') || isnumeric(app.Current_classification))
                    unclassified = find(isnan(app.All_Sort(app.SelectedEpochs)));       % Get the unclassified epochs
                    if isempty(unclassified)
                        jj = jj - 1;
                    else
                        jj = app.SelectedEpochs(unclassified(randperm(length(unclassified),1)))-1;    % Get the random epoch from the unclassified ones
                    end
                end
                
                jj = jj + 1;
                
                if jj > size(app.DATA.LFP_epochs,1)
                    % Enable the user to finish the inspection
                    app.CompleteInspectionButton.Enable = 'on';
                    
                    % Reduce the counter in -1
                    jj = jj - 1;
                end                
                
                % Get the number of NaN epochs
                nan_epochs = 0;
                while sum(isnan(app.DATA.LFP_epochs(jj,:))) > 0 || sum(isnan(app.DATA.EMG_epochs(jj,:))) > 0
                    jj=jj+1;
                    nan_epochs = nan_epochs + 1;
                end
                
                % Gather important information
                epoch_num = jj;
                CA1_lfp = app.DATA.LFP_epochs(jj,:);
                EMG_rec = app.DATA.EMG_epochs(jj,:);
                Fidx = app.LFP.LFP.Frequency_distribution<=90;
                freq_vector = app.LFP.LFP.Frequency_distribution(Fidx);
                PSD_data = app.LFP.LFP.Power_normalized(jj,Fidx);
                % Exclude the noise frequencies
                PSD_data(app.LFP.LFP.Frequency_distribution(Fidx) >= app.Notch_frequencies.base-app.Notch_frequencies.extension & ...
                    app.LFP.LFP.Frequency_distribution(Fidx) <= app.Notch_frequencies.base+app.Notch_frequencies.extension) = NaN;

                
                % Sends necessary information to the app
                updatePlot(app, time_vector_LFP, time_vector_EMG, epoch_num, CA1_lfp, EMG_rec, freq_vector, PSD_data)
                % Make sure that the execution of this function will wait until the
                % user selects an option (app_handle.UIFigure is the figure associated
                % with the app
                
                uiwait(app.UIFigure)
                
                
                % app_handle.Current_classification is a value representing the
                % classification for the current period (jj)
                % Check the Current_classification and accordingly
                switch app.Current_classification
                    case 'previous' % Previous button or left array pressed
                        if jj == 1  % If this is the first epoch, 'previous' button will not do anything
                            jj = 0;  % Reset the loop
                        else
                            jj = jj - 2 - nan_epochs; % j - 2 will effectively go to the previous epoch ( - nan_epochs is considering any NaN epoch)
                        end
                        
                    case 'next' % Next button or left array pressed
                        % Changes to the next epoch (Basically, do nothing!)
                    case 'next_from_list'
                        jj = select_from_list(app,jj,'next');
                    case 'previous_from_list'
                        jj = select_from_list(app,jj,'previous');
                    case 1  % AWAKE
                        app.All_Sort(jj) = 3;
                    case 2  % NREM
                        app.All_Sort(jj) = 2;
                    case 3  % REM
                        app.All_Sort(jj) = 1;
                    case 4 % AWAKE <--> NREM
                        app.All_Sort(jj) = 4;
                    case 5 % NREM <--> REM
                        app.All_Sort(jj) = 5;
                    case 6 % REM <--> AWAKE
                        app.All_Sort(jj) = 6;
                    case 0 % None of those
                        app.All_Sort(jj) = 0;
                    case 7 % Micro-arousal nREM
                        app.All_Sort(jj) = 7;
                    case 8 % Micro-arousal REM
                        app.All_Sort(jj) = 8;
                    
                    case 'epoch_list_selected'
                        % Get the selected value from the drop box list
                        jj = app.Epoch_list_selected - 1;
                        
                    case 'Keep'
                        % Keep the current epochs
                        jj = jj - 1;
                        
                    case 'stop_and_save'    % The user pressed the button to 'stop' the inspection
                        app.Visual_inspection.inspection_finished = false; % Informs the algorithm that the inspection has not been finished yet
                        % Finishes inspection loop
                        break
                        
                    case 'complete_inspection'
                        app.Visual_inspection.inspection_finished = true; % Informs the algorithm that the inspection has not been finished yet
                        % Finishes inspection loop
                        break
                        
                    case 'finish_re_inspection' % If the button 'Finish re-inspection' have been pressed (when enabled)
                        % Combine the previous classification with the new one by
                        % excluding different classified epochs
                        
                        % AWAKE
                        Visual_inspection_bkp.AWAKE_idx(ismember(Visual_inspection_bkp.AWAKE_idx,[Visual_inspection.NREM_idx Visual_inspection.REM_idx Visual_inspection.Transition.AWA_NREM Visual_inspection.Transition.NREM_REM ...
                            Visual_inspection.Transition.REM_AWA Visual_inspection.Transition.unknown])) = [];
                        % NREM
                        Visual_inspection_bkp.NREM_idx(ismember(Visual_inspection_bkp.NREM_idx,[Visual_inspection.AWAKE_idx Visual_inspection.REM_idx Visual_inspection.Transition.AWA_NREM Visual_inspection.Transition.NREM_REM ...
                            Visual_inspection.Transition.REM_AWA Visual_inspection.Transition.unknown])) = [];
                        % NREM
                        Visual_inspection_bkp.REM_idx(ismember(Visual_inspection_bkp.REM_idx,[Visual_inspection.AWAKE_idx Visual_inspection.NREM_idx Visual_inspection.Transition.AWA_NREM Visual_inspection.Transition.NREM_REM ...
                            Visual_inspection.Transition.REM_AWA Visual_inspection.Transition.unknown])) = [];
                        %                 % Transition AWA-NREM
                        %                 Visual_inspection_bkp.Transition.REM_AWA(ismember(Visual_inspection_bkp.Transition.REM_AWA,[Visual_inspection.AWAKE_idx Visual_inspection.NREM_idx Visual_inspection.REM_idx Visual_inspection.Transition.NREM_REM ...
                        %                     Visual_inspection.Transition.REM_AWA Visual_inspection.Transition.unknown])) = [];
                        %                 % Transition NREM-REM
                        %                 Visual_inspection_bkp.Transition.NREM_REM(ismember(Visual_inspection_bkp.Transition.NREM_REM,[Visual_inspection.AWAKE_idx Visual_inspection.NREM_idx Visual_inspection.REM_idx Visual_inspection.Transition.AWA_NREM ...
                        %                     Visual_inspection.Transition.REM_AWA Visual_inspection.Transition.unknown])) = [];
                        %                 % Transition REM-AWA
                        %                 Visual_inspection_bkp.Transition.REM_AWA(ismember(Visual_inspection_bkp.Transition.REM_AWA,[Visual_inspection.AWAKE_idx Visual_inspection.NREM_idx Visual_inspection.REM_idx Visual_inspection.Transition.AWA_NREM ...
                        %                     Visual_inspection.Transition.NREM_REM Visual_inspection.Transition.unknown])) = [];
                        %                 % Transition unknown
                        %                 Visual_inspection_bkp.Transition.unknown(ismember(Visual_inspection_bkp.Transition.unknown,[Visual_inspection.AWAKE_idx Visual_inspection.NREM_idx Visual_inspection.REM_idx Visual_inspection.Transition.AWA_NREM ...
                        %                     Visual_inspection.Transition.NREM_REM Visual_inspection.Transition.REM_AWA])) = [];
                        
                        % Concatenate the backup and new classification
                        Visual_inspection.AWAKE_idx = unique([Visual_inspection.AWAKE_idx Visual_inspection_bkp.AWAKE_idx'],'stable');
                        Visual_inspection.NREM_idx = unique([Visual_inspection.NREM_idx Visual_inspection_bkp.NREM_idx'],'stable');
                        Visual_inspection.REM_idx = unique([Visual_inspection.REM_idx Visual_inspection_bkp.REM_idx'],'stable');
                        %                 Visual_inspection.Transition.AWA_NREM = unique([Visual_inspection.Transition.AWA_NREM Visual_inspection_bkp.Transition.AWA_NREM]);
                        %                 Visual_inspection.Transition.NREM_REM = unique([Visual_inspection.Transition.NREM_REM Visual_inspection_bkp.Transition.NREM_REM]);
                        %                 Visual_inspection.Transition.REM_AWA = unique([Visual_inspection.Transition.REM_AWA Visual_inspection_bkp.Transition.REM_AWA]);
                        %                 Visual_inspection.Transition.unknown = unique([Visual_inspection.Transition.unknown Visual_inspection_bkp.Transition.unknown]);
                        %
                        break % Finishes the loop
                end
                
                % Updates the epochs list
                updateEpochs_complete(app)
                                
            end
            
            % Save the manual classification
            visual_inspection = app.Visual_inspection;
            all_Sort = app.All_Sort;
            last_checked = jj;
            save(fullfile(app.Output_path,'Manual_classification'),'visual_inspection','all_Sort','last_checked')
            
        end

        % Value changed function: CheckBox_AWAKE
        function CheckBox_AWAKEValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Value changed function: CheckBox_NREM
        function CheckBox_NREMValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Value changed function: CheckBox_REM
        function CheckBox_REMValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Value changed function: CheckBox_remaining
        function CheckBox_remainingValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Value changed function: CheckBox_None
        function CheckBox_NoneValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Value changed function: SelectepochListBox
        function SelectepochListBoxValueChanged(app, event)
            % Epoch number selected
            app.Epoch_list_selected = str2double(app.SelectepochListBox.Value);
            % Change the Current Classification tag
            app.Current_classification = 'epoch_list_selected';
            % Resume the program execution
            uiresume(app.UIFigure)
        end

        % Button pushed function: CompleteInspectionButton
        function CompleteInspectionButtonPushed(app, event)
             % Change the Current Classification tag
            app.Current_classification = 'complete_inspection';
            % Resume the program execution
            uiresume(app.UIFigure)
        end

        % Value changed function: CheckBox_AWAKE_NREM
        function CheckBox_AWAKE_NREMValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';            
        end

        % Value changed function: CheckBox_NREM_REM
        function CheckBox_NREM_REMValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Value changed function: CheckBox_REM_WK
        function CheckBox_REM_WKValueChanged(app, event)
            updateEpochs_complete(app)
            app.Current_classification = 'Keep';
        end

        % Button pushed function: NREM_microarousal_button
        function NREM_microarousal_buttonPushed(app, event)
            app.Current_classification = 7; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Button pushed function: REM_microarousal_button
        function REM_microarousal_buttonButtonPushed(app, event)
            app.Current_classification = 8; % Change the current epoch classification accordingly
            uiresume(app.UIFigure)  % Resume the execution of the visual inspection
        end

        % Value changed function: SelectedEpochsCheckBox
        function SelectedEpochsCheckBoxValueChanged(app, event)
            
            % If the user has selected the Selected Epochs checkbox
            if app.SelectedEpochsCheckBox.Value
                
                % Uncheck the randomize check box value
                app.RandomizeCheckBox.Value = false;
                
                % Define the selected epochs (accordingly to Mateus'
                % classification
                app.SelectedEpochs = [1	7	9	13	42	59	71	76	77	107	129	138	171	248	278	301	307	311	365	373	401	430	464	466	511	515	520	541	578	651	653	672	699	714	720	727	755	773	796	802	815	830	832	835	839	842	850	901	902	913	919	936	950	959	1010	1015	1018	1025	1033	1069	1081	1095	1138	1143	1159	1172	1174	1187	1194	1223	1249	1254	1257	1281	1286	1288	1307	1310	1316	1321	1327	1342	1347	1359	1397	1402	1428	1439	1444	1463	1472	1474	1494	1501	1508	1512	1534	1538	1587	1589	1596	1612	1625	1634	1642	1646	1651	1652	1693	1703	1708	1715	1791	1795	1849	1872	1907	1920	1925	1943	1949	1954	1956	1969	1992	2005	2023	2027	2041	2043	2062	2066	2068	2083	2088	2095	2146	2161	2169	2174	2177	2189	2192	2197	2200	2208	2219	2229	2239	2240	2245	2250	2251	2252	2263	2265	2269	2271	2281	2287	2295	2302	2304	2327	2337	2339	2357	2361	2383	2392	2407	2410	2415	2424	2428	2435	2474	2489	2501	2514	2527	2528	2531	2534	2542	2544	2576	2585	2612	2615	2618	2644	2652	2659	2664	2682	2690	2691	2702	2715	2750	2753	2758	2761	2774	2797	2808	2820	2839	2869	2899	2911	2923	2924	2926	2936	2944	2949	2954	2962	2985	2991	3017	3021	3038	3041	3048	3050	3053	3073	3118	3159	3161	3168	3177	3192	3200	3206	3209	3216	3226	3233	3235	3267	3268	3277	3294	3303	3312	3314	3342	3360	3365	3380	3390	3397	3400	3404	3411	3422	3426	3432	3434	3443	3453	3462	3472	3478	3496	3517	3519	3522	3540	3545	3559	3595	3618	3621	3622	3625	3632	3634	3667	3678	3681	3684	3694	3695	3698	3701	3702	3709	3726	3744	3749	3750	3754	3758	3786	3795	3813	3820	3826	3827	3836	3847	3857	3858	3859	3873	3877	3893	3915	3926	3929	3947	3986	3997	4000	4010	4011	4016	4019	4031	4039	4077	4097	4100	4109	4111	4129	4176	4181	4195	4209	4218	4228	4236	4253	4254	4258	4263	4268	4303	4318	4319	4327	4328	4329	4331	4334	4337	4344	4349	4351	4353	4360	4400	4428	4432	4464	4489	4534	4540	4543	4556	4582	4587	4591	4594	4610	4625	4627	4628	4638	4660	4664	4676	4695	4715	4749	4752	4759	4765	4770	4773	4779	4789	4814	4883	4888	4895	4912	4918	4929	4938	4974	4976	5003	5007	5009	5018	5036	5038	5080	5107	5108	5123	5132	5172	5208	5225	5239	5284	5310	5336	5341	5342	5363	5366	5367	5391	5398	5401	5427	5437	5446	5465	5475	5491	5548	5558	5604	5631	5635	5671	5677	5679	5682	5685	5688	5740	5783	5799	5805	5830	5832	5833	5839	5863	5869	5873	5884	5891	5896	5897	5901	5918	5958	5974	6021	6035	6046	6049	6053	6058	6063	6073	6116	6146	6158	6168	6180	6202	6262	6325	6328	6339	6386	6390	6403	6427	6431	6432	6447	6450	6465	6478	6488	6492	6496	6504	6508	6527	6536	6541	6550	6553	6555	6560	6563	6582	6614	6626	6633	6643	6669	6672	6676	6680	6684	6699	6725	6749	6751	6755	6763	6769	6805	6851	6858	6860	6880	6886	6930	6933	6934	6936	6951	6952	6962	6982	7014	7034	7052	7060	7072	7083	7084	7087	7091	7107	7115	7130	7132	7145	7153	7154	7164	7170	7171	7197	7208	7226	7235	7280	7301	7319	7327	7350	7378	7389	7409	7442	7472	7534	7582	7609	7654	7658	7668	7685	7706	7707	7784	7794	7808	7824	7826	7836	7838	7875	7887	7898	7899	7904	7921	7926	7933	7940	7962	7988	8030	8033	8035	8039	8045	8071	8076	8121	8130	8147	8153	8170	8176	8212	8214	8229	8267	8274	8280	8293	8295	8299	8319	8343	8376	8391	8413	8415	8463	8473	8488	8494	8496	8509	8542	8550	8559	8634];
                
            end
            
        end

        % Value changed function: RandomizeCheckBox
        function RandomizeCheckBoxValueChanged(app, event)
            
            % Uncheck the selected epochs check box value
            app.SelectedEpochsCheckBox.Value = false;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.NoneofthoseButton = uibutton(app.UIFigure, 'push');
            app.NoneofthoseButton.ButtonPushedFcn = createCallbackFcn(app, @NoneofthoseButtonPushed, true);
            app.NoneofthoseButton.Text = '0 - None of those';
            app.IamsurePanel = uipanel(app.UIFigure);
            app.IamsurePanel.Title = 'I am sure!';
            app.AWAKEButton = uibutton(app.IamsurePanel, 'push');
            app.AWAKEButton.ButtonPushedFcn = createCallbackFcn(app, @AWAKEButtonPushed, true);
            app.AWAKEButton.Text = '1 - AWAKE';
            app.NREMButton = uibutton(app.IamsurePanel, 'push');
            app.NREMButton.ButtonPushedFcn = createCallbackFcn(app, @NREMButtonPushed, true);
            app.NREMButton.Text = '2 - NREM';
            app.REMButton = uibutton(app.IamsurePanel, 'push');
            app.REMButton.ButtonPushedFcn = createCallbackFcn(app, @REMButtonPushed, true);
            app.REMButton.Text = '3 - REM';
            app.TransitionPanel = uipanel(app.UIFigure);
            app.TransitionPanel.Title = 'Transition';
            app.AWAKENREMButton = uibutton(app.TransitionPanel, 'push');
            app.AWAKENREMButton.ButtonPushedFcn = createCallbackFcn(app, @AWAKENREMButtonPushed, true);
            app.AWAKENREMButton.Text = '4 - AWAKE <--> NREM';
            app.NREMREMButton = uibutton(app.TransitionPanel, 'push');
            app.NREMREMButton.ButtonPushedFcn = createCallbackFcn(app, @NREMREMButtonPushed, true);
            app.NREMREMButton.Text = '5 - NREM <--> REM';
            app.REMAWAKEButton = uibutton(app.TransitionPanel, 'push');
            app.REMAWAKEButton.ButtonPushedFcn = createCallbackFcn(app, @REMAWAKEButtonPushed, true);
            app.REMAWAKEButton.Text = '6 - REM <--> AWAKE';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FinishreinspectionButton = uibutton(app.UIFigure, 'push');
            app.FinishreinspectionButton.ButtonPushedFcn = createCallbackFcn(app, @FinishreinspectionButtonPushed, true);
            app.FinishreinspectionButton.Visible = 'off';
            app.FinishreinspectionButton.Text = 'Finish re-inspection';
            app.StopandsaveinspectionButton = uibutton(app.UIFigure, 'push');
            app.StopandsaveinspectionButton.ButtonPushedFcn = createCallbackFcn(app, @StopandsaveinspectionButtonPushed, true);
            app.StopandsaveinspectionButton.Text = {'Stop and save'; ' inspection'};
            app.AWAKELabel = uilabel(app.UIFigure);
            app.AWAKELabel.Text = 'AWAKE: ';
            app.NREMLabel = uilabel(app.UIFigure);
            app.NREMLabel.Text = 'NREM:';
            app.REMLabel = uilabel(app.UIFigure);
            app.REMLabel.Text = 'REM:';
            app.NumberofepochsclassifiedLabel = uilabel(app.UIFigure);
            app.NumberofepochsclassifiedLabel.Text = {'Number of '; 'epochs classified'};
            app.Label_awake = uilabel(app.UIFigure);
            app.Label_awake.Text = '0';
            app.Label_nrem = uilabel(app.UIFigure);
            app.Label_nrem.Text = '0';
            app.Label_rem = uilabel(app.UIFigure);
            app.Label_rem.Text = '0';
            app.Remaining_number = uilabel(app.UIFigure);
            app.Remaining_number.Text = '0';
            app.RemainingLabel = uilabel(app.UIFigure);
            app.RemainingLabel.Text = 'Remaining:';
            app.Panel = uipanel(app.UIFigure);
            app.LoadDataButton = uibutton(app.Panel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.stLabel = uilabel(app.Panel);
            app.stLabel.Text = '1st';
            app.SelecttheDirectorycontainingdata_variablesandALL_DATAfilesLabel = uilabel(app.Panel);
            app.SelecttheDirectorycontainingdata_variablesandALL_DATAfilesLabel.Text = {'Select the Directory '; 'containing ''data_variables'' '; 'and ''ALL_DATA'' files'};
            app.NotchHzEditFieldLabel = uilabel(app.Panel);
            app.NotchHzEditFieldLabel.Text = 'Notch (Hz)';
            app.NotchHzEditField = uieditfield(app.Panel, 'numeric');
            app.NotchHzEditField.Value = 50;
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ndLabel = uilabel(app.Panel);
            app.ndLabel.Text = '2nd';
            app.CompleteInspectionButton = uibutton(app.Panel, 'push');
            app.CompleteInspectionButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteInspectionButtonPushed, true);
            app.CompleteInspectionButton.Enable = 'off';
            app.CompleteInspectionButton.Text = 'Complete Inspection';
            app.rdLabel = uilabel(app.Panel);
            app.rdLabel.Text = '3rd';
            app.SelectepochLabel = uilabel(app.UIFigure);
            app.SelectepochLabel.Text = 'Select epoch';
            app.SelectepochListBox = uilistbox(app.UIFigure);
            app.SelectepochListBox.ValueChangedFcn = createCallbackFcn(app, @SelectepochListBoxValueChanged, true);
            app.CheckBox_AWAKE = uicheckbox(app.UIFigure);
            app.CheckBox_AWAKE.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AWAKEValueChanged, true);
            app.CheckBox_AWAKE.Text = '';
            app.CheckBox_NREM = uicheckbox(app.UIFigure);
            app.CheckBox_NREM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NREMValueChanged, true);
            app.CheckBox_NREM.Text = '';
            app.CheckBox_REM = uicheckbox(app.UIFigure);
            app.CheckBox_REM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_REMValueChanged, true);
            app.CheckBox_REM.Text = '';
            app.CheckBox_remaining = uicheckbox(app.UIFigure);
            app.CheckBox_remaining.ValueChangedFcn = createCallbackFcn(app, @CheckBox_remainingValueChanged, true);
            app.CheckBox_remaining.Text = '';
            app.CheckBox_remaining.Value = true;
            app.None_number = uilabel(app.UIFigure);
            app.None_number.Text = '0';
            app.NoneLabel = uilabel(app.UIFigure);
            app.NoneLabel.Text = 'None:';
            app.CheckBox_None = uicheckbox(app.UIFigure);
            app.CheckBox_None.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NoneValueChanged, true);
            app.CheckBox_None.Text = '';
            app.WKNREMLabel = uilabel(app.UIFigure);
            app.WKNREMLabel.Text = 'WK<->NREM';
            app.NREMREMLabel = uilabel(app.UIFigure);
            app.NREMREMLabel.Text = 'NREM<->REM';
            app.REMWKLabel = uilabel(app.UIFigure);
            app.REMWKLabel.Text = 'REM<->WK';
            app.Label_awake_nrem = uilabel(app.UIFigure);
            app.Label_awake_nrem.Text = '0';
            app.Label_nrem_rem = uilabel(app.UIFigure);
            app.Label_nrem_rem.Text = '0';
            app.Label_rem_wk = uilabel(app.UIFigure);
            app.Label_rem_wk.Text = '0';
            app.CheckBox_AWAKE_NREM = uicheckbox(app.UIFigure);
            app.CheckBox_AWAKE_NREM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AWAKE_NREMValueChanged, true);
            app.CheckBox_AWAKE_NREM.Text = '';
            app.CheckBox_NREM_REM = uicheckbox(app.UIFigure);
            app.CheckBox_NREM_REM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_NREM_REMValueChanged, true);
            app.CheckBox_NREM_REM.Text = '';
            app.CheckBox_REM_WK = uicheckbox(app.UIFigure);
            app.CheckBox_REM_WK.ValueChangedFcn = createCallbackFcn(app, @CheckBox_REM_WKValueChanged, true);
            app.CheckBox_REM_WK.Text = '';
            app.MicroarousalPanel = uipanel(app.UIFigure);
            app.MicroarousalPanel.Title = 'Micro-arousal';
            app.NREM_microarousal_button = uibutton(app.MicroarousalPanel, 'push');
            app.NREM_microarousal_button.ButtonPushedFcn = createCallbackFcn(app, @NREM_microarousal_buttonPushed, true);
            app.NREM_microarousal_button.Text = '7 - NREM';
            app.REM_microarousal_button = uibutton(app.MicroarousalPanel, 'push');
            app.REM_microarousal_button.ButtonPushedFcn = createCallbackFcn(app, @REM_microarousal_buttonButtonPushed, true);
            app.REM_microarousal_button.Text = '8 - REM';
            app.RandomizeCheckBox = uicheckbox(app.UIFigure);
            app.RandomizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizeCheckBoxValueChanged, true);
            app.RandomizeCheckBox.Text = 'Randomize';
            app.SelectedEpochsCheckBox = uicheckbox(app.UIFigure);
            app.SelectedEpochsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectedEpochsCheckBoxValueChanged, true);
            app.SelectedEpochsCheckBox.Text = 'Selected Epochs';
            app.UIAxes_CA1 = uiaxes(app.UIFigure);
            title(app.UIAxes_CA1, 'Title')
            ylabel(app.UIAxes_CA1, {'Hippocampus'; '(mV)'})
            app.UIAxes_CA1.YLim = [-1 1];
            app.UIAxes_CA1.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_CA1.YTick = [-1 0 1];
            app.UIAxes_PSD = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_PSD, 'Frequency (Hz)')
            ylabel(app.UIAxes_PSD, {'PSD'; 'Power Norm'})
            app.UIAxes_PSD.XLim = [1 90];
            app.UIAxes_PSD.YLim = [0.0001 0.01];
            app.UIAxes_PSD.XTick = [2 4 6 8 10 20 40 60 80];
            app.UIAxes_PSD.XScale = 'log';
            app.UIAxes_PSD.XTickLabel = {'2'; '4'; '6'; '8'; '10'; '20'; '40'; '60'; '80'};
            app.UIAxes_PSD.YScale = 'log';
            app.UIAxes_EMG = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_EMG, 'Seconds')
            ylabel(app.UIAxes_EMG, {'EMG'; '(mV)'})
            app.UIAxes_EMG.YLim = [-1 1];
            app.UIAxes_EMG.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_EMG.YTick = [-1 -0.5 0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual_classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end